classdef doan_cau3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        NameUI              matlab.ui.control.Label
        NNum                matlab.ui.control.NumericEditField
        NLabel              matlab.ui.control.Label
        bNum                matlab.ui.control.NumericEditField
        bLabel              matlab.ui.control.Label
        aNum                matlab.ui.control.NumericEditField
        aLabel              matlab.ui.control.Label
        functX              matlab.ui.control.EditField
        fxLabel             matlab.ui.control.Label
        Button              matlab.ui.control.Button
        PrecisionReturn     matlab.ui.control.Label
        Precision           matlab.ui.control.Label
        SimpsonReturn       matlab.ui.control.Label
        Simpson             matlab.ui.control.Label
        TrapzoidReturn      matlab.ui.control.Label
        Trapzoid            matlab.ui.control.Label
        MSSV                matlab.ui.control.EditField
        MSSVEditFieldLabel  matlab.ui.control.Label
        HoTen               matlab.ui.control.EditField
        HtnEditFieldLabel   matlab.ui.control.Label
        UIAxes              matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % Nhập các thông số từ giao diện
            f = app.functX.Value; % Nhập hàm f(x) dạng string
            a = app.aNum.Value; % Nhập cận dưới tích phân
            b = app.bNum.Value; % Nhập cận trên tích phân
            N = app.NNum.Value; % Nhập số phần đoạn

            % Chuyển dạng f từ string -> function handle
            fx = str2func(['@(x)', f]);

            % Tính tích phân bằng các phương pháp 
            y1 = tichphanhinhthang(fx,a,b,N);
            y2 = tichphanSimpson(fx,a,b,N);
            y = integral(fx,a,b);
            
            % Trả kết quả hiện thị ra Label
            app.TrapzoidReturn.Text = sprintf('%.10f',y1);
            app.SimpsonReturn.Text = sprintf('%.10f',y2);
            app.PrecisionReturn.Text = sprintf('%.10f',y);

            % Vẽ đồ thị
            x_arr = linspace(a,b,N);
            y_fx = arrayfun(fx, x_arr); % Tính giá trị hàm tại các điểm trong x_arr
            plot(app.UIAxes,x_arr,y_fx,'r');
            legend(app.UIAxes, 'f(x)', 'Location', 'best','Interpreter','latex',fontsize=14);
            grid(app.UIAxes,"on");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Đồ thị f(x) trong khoảng tính tích phân')
            xlabel(app.UIAxes, 'Khoảng lấy tích phân')
            ylabel(app.UIAxes, 'Biên độ')
            zlabel(app.UIAxes, 'Z')
            app.HtnEditFieldLabel = uilabel(app.UIFigure);
            app.HtnEditFieldLabel.Text = 'Họ tên';
            app.HoTen = uieditfield(app.UIFigure, 'text');
            app.HoTen.Value = 'Lê Minh Đức';
            app.MSSVEditFieldLabel = uilabel(app.UIFigure);
            app.MSSVEditFieldLabel.Text = 'MSSV';
            app.MSSV = uieditfield(app.UIFigure, 'text');
            app.MSSV.Value = '22200034';
            app.Trapzoid = uilabel(app.UIFigure);
            app.Trapzoid.Interpreter = 'latex';
            app.Trapzoid.Text = 'Phương pháp hình thang:';
            app.TrapzoidReturn = uilabel(app.UIFigure);
            app.TrapzoidReturn.Text = '';
            app.Simpson = uilabel(app.UIFigure);
            app.Simpson.Interpreter = 'latex';
            app.Simpson.Text = 'Phương pháp SimpSon:';
            app.SimpsonReturn = uilabel(app.UIFigure);
            app.SimpsonReturn.Text = '';
            app.Precision = uilabel(app.UIFigure);
            app.Precision.Interpreter = 'latex';
            app.Precision.Text = 'Tích phân chính xác:';
            app.PrecisionReturn = uilabel(app.UIFigure);
            app.PrecisionReturn.Text = '';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.fxLabel = uilabel(app.UIFigure);
            app.fxLabel.Text = 'f(x) =';
            app.functX = uieditfield(app.UIFigure, 'text');
            app.functX.Value = 'x.^3.*sin(x) + x.*cos(x)';
            app.aLabel = uilabel(app.UIFigure);
            app.aLabel.Text = 'a =';
            app.aNum = uieditfield(app.UIFigure, 'numeric');
            app.bLabel = uilabel(app.UIFigure);
            app.bLabel.Text = 'b =';
            app.bNum = uieditfield(app.UIFigure, 'numeric');
            app.bNum.Value = 1;
            app.NLabel = uilabel(app.UIFigure);
            app.NLabel.Text = 'N =';
            app.NNum = uieditfield(app.UIFigure, 'numeric');
            app.NNum.Value = 100;
            app.NameUI = uilabel(app.UIFigure);
            app.NameUI.Text = 'GIAO DIỆN TÍNH GẦN ĐÚNG TÍCH PHÂN';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = doan_cau3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end